classdef gameUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Rock_Obstacle        matlab.ui.control.Image
        StartGameButton      matlab.ui.control.StateButton
        Player               matlab.ui.control.Image
        ScoreEditField       matlab.ui.control.NumericEditField
        ScoreEditFieldLabel  matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Keeps track of the current game state 
        % 0 = Game not running; 1 = Game running
        gameRunning; 
        gameTimer; %Timer for controlling game loop
    end
    
    methods (Access = public)
        
        function obstacleMovement(app)
            disp(['Rock Position Before: ', num2str(app.Rock_Obstacle.Position(1))]);
            if app.Rock_Obstacle.Position(1) > -app.Rock_Obstacle.Position(3)  % Check if it's completely off the screen
                app.Rock_Obstacle.Position(1) = app.Rock_Obstacle.Position(1) - 5;  % Move left
            else
                app.Rock_Obstacle.Position(1) = app.UIFigure.Position(3);  % Reset to the right side of the figure
            end
            disp(['Rock Position After: ', num2str(app.Rock_Obstacle.Position(1))]);
        end

    end
    
    methods (Access = private)
        
        function setUpGameTimer(app)
            app.gameTimer = timer('ExecutionMode','fixedRate', ...
                'Period', 0.05,  ... %Speed of game loop
                'TimerFcn', @(src, event)gameLoop(app));
            
        end
        
        function gameLoop(app)
            disp('Game loop running');
            obstacleMovement(app); %Move obstacle

            if isGameOver(app)
                stopGame(app);
            end
        end
        
        function resetGame(app)
            % Reset game elements to initial state
            app.Rock_Obstacle.Position = [988 100 59 61]; % Reset obstacle position
            app.ScoreEditField.Value = 0;  % Reset score
        end

        function gameOver = isGameOver(app)
            % Simple collision detection logic (to be improved)
            gameOver = app.Player.Position(1) == app.Rock_Obstacle.Position(1);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function UIFiguresStartupFcn(app)
            setUpGameTimer(app);
        end

        % Value changed function: StartGameButton
        function StartButtonPressed(app, event)
            % value is 0 on default/unpressed, 1 when pressed
            app.gameRunning = app.StartGameButton.Value; 

            if ~app.gameRunning   
                % If the game is not running
                % Display "Start Game" button
                app.StartGameButton.Text = "Start Game";
                % Hide Images
                app.Player.Visible = "off";
                app.Rock_Obstacle.Visible = "off"
                stop(app.gameTimer); % Stopping gameTimer
                resetGame(app); %Resets the game 
            else                   
                % If the game is running
                % Display "End Game" button
                app.StartGameButton.Text = "End Game";
                % Show Images
                app.Player.Visible = "on";
                app.Rock_Obstacle.Visible = "on";
                start(app.gameTimer); %Starting gameTimer

                % Make the obstacle move
                obstacleMovement(app);
            end   
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            initialY = 100; % Initial Y position
            jumpHeight = 100; % Height to jump
            velocity = 5; % Velocity of jump

            if strcmp(key, 'space') % Check if spacebar is pressed
                % Get the current position of the image
                currentPosition = app.Player.Position;
                currentY = currentPosition(2);
                
                % Jump animation
                while currentY < initialY + jumpHeight
                    % Move the image up
                    currentY = currentY + velocity;
                    app.Player.Position = [currentPosition(1) currentY currentPosition(3:4)];
                    drawnow; % Update the figure
                end

                % Come back down
                while currentY > initialY
                    % Move the image down
                    currentY = currentY - velocity
                    app.Player.Position = [currentPosition(1) currentY currentPosition(3:4)];
                    drawnow; % Update the figure
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ScoreEditFieldLabel = uilabel(app.UIFigure);
            app.ScoreEditFieldLabel.Text = 'Score';
            app.ScoreEditField = uieditfield(app.UIFigure, 'numeric');
            app.Player = uiimage(app.UIFigure);
            app.Player.Visible = 'off';
            app.Player.ImageSource = fullfile(pathToMLAPP, 'mickey_mouse.png');
            app.StartGameButton = uibutton(app.UIFigure, 'state');
            app.StartGameButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonPressed, true);
            app.StartGameButton.Text = 'Start Game';
            app.Rock_Obstacle = uiimage(app.UIFigure);
            app.Rock_Obstacle.Visible = 'off';
            app.Rock_Obstacle.ImageSource = fullfile(pathToMLAPP, 'rock_obstacle.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gameUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @UIFiguresStartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end